classdef B1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        STOPButton     matlab.ui.control.StateButton
        ExponentLabel  matlab.ui.control.Label
        BaseLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
%            axes12 = polaraxes;
% %            polaraxes(axes12);
%             angle_p=zeros(1,181);
%              a=1;
%              for theta=-pi/2:pi/180:pi/2;
%                angle_p(a)=theta;
%                a=a+1;
%              end
%            for i = 1:100 
%              recObj = audiorecorder(16000,16,4,1);
%              recordblocking(recObj,0.4);
%              X1 = getaudiodata(recObj);
%              X(:,4) = X1(:,2);
%              X(:,3) = X1(:,1);
%              X(:,2) = X1(:,4);
%              X(:,1) = X1(:,3);
%              Y = Broadband__doa(X);
%              
%              polarplot(angle_p,real(10*log(Y')));
%              axes12.ThetaZeroLocation = 'top';
%              axes12.ThetaLim = [-90,90];
%              rlim([0 5])
%            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Replace 0 in equation with unicode theta
            
            
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
         
%            polaraxes(axes12);
            angle_p=zeros(1,181);
             a=1;
             for theta=-pi/2:pi/180:pi/2;
               angle_p(a)=theta;
               a=a+1;
             end
           timeLength=0.1;                                                                      % 采样时长，单位秒
           samples=timeLength*16000;                                                       % 默认采样率44100，计算采样点数
           H = audioDeviceReader('SamplesPerFrame',5120, ...
               'BitDepth','16-bit integer','Device','麦克风 (USB YDB01 Audio Effect)', ...
               'NumChannels',4,'SampleRate',16000) %录音的工具H
           [audioIn,~] = step(H); %把录音的内容提取出来
           pause(2);
           while true
             [audioIn,Overrun] = step(H); 
             X(:,4) = audioIn(:,2);
             X(:,3) = audioIn(:,1);
             X(:,2) = audioIn(:,4);
             X(:,1) = audioIn(:,3);
             [Y,angel1,angel2] = Broadband__doa(X);
              app.BaseLabel.Text =[angel1,' ',angel2];
             polarplot(app.Pax,angle_p,real(10*log(Y')));
             set(app.Pax,'ThetaZeroLocation','top','ThetaLim',[-90,90])
             drawnow
             pause(0.01)
           end

            % Plot the polar function
%             updateplot(app);
        end

        % Callback function
        function ComponentValueChanged(app, event)
            % Plot the polar function
          
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            quit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'r = ( 1 + sin(a0) )';
            app.ExponentLabel = uilabel(app.UIFigure);
            app.ExponentLabel.Text = '';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end