classdef Bonus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        STOPButton     matlab.ui.control.StateButton
        ExponentLabel  matlab.ui.control.Label
        BaseLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
%            axes12 = polaraxes;
% %            polaraxes(axes12);
%             angle_p=zeros(1,181);
%              a=1;
%              for theta=-pi/2:pi/180:pi/2;
%                angle_p(a)=theta;
%                a=a+1;
%              end
%            for i = 1:100 
%              recObj = audiorecorder(16000,16,4,1);
%              recordblocking(recObj,0.4);
%              X1 = getaudiodata(recObj);
%              X(:,4) = X1(:,2);
%              X(:,3) = X1(:,1);
%              X(:,2) = X1(:,4);
%              X(:,1) = X1(:,3);
%              Y = Broadband__doa(X);
%              
%              polarplot(angle_p,real(10*log(Y')));
%              axes12.ThetaZeroLocation = 'top';
%              axes12.ThetaLim = [-90,90];
%              rlim([0 5])
%            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Replace 0 in equation with unicode theta
            app.BaseLabel.Text = ['r = ( 1 + sin(a' char(00952) ') )'];
            
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
         
%            polaraxes(axes12);
            angle_p=zeros(1,181);%用来画图的横坐标
             a=1; %索引，每循环一个角度增加1
             for theta=-pi/2:pi/180:pi/2;%以pi/180的步长遍历-90-90
               angle_p(a)=theta;%把每个角度存进数组里去
               a=a+1;
             end
           while true
             recObj = audiorecorder(16000,16,4,1);%公式是（Fs,nBits,numchannels,ID）由于这个函数只能接受最多两通道的麦克风，所以我们对函数进行了修改，使得可以接受四通道的麦克风
             recordblocking(recObj,0.3);%录制音频的工具 前者指的是录制出的对象，后者指的是录制对象秒数
             X1 = getaudiodata(recObj);%把录制好的音频存到数值数组中去，四列数组，因为有四通道
             X(:,4) = X1(:,2);%我们发现数组的顺序与麦克风的顺序有所不同，这里是X1数组里的第二列对应第四个麦克风接收到的信号，下述同理
             X(:,3) = X1(:,1);
             X(:,2) = X1(:,4);
             X(:,1) = X1(:,3);
             [Y,angel1,angel2] = Broadband__doa(X);%这里调用了Broadband__doa函数，输出P_value和两个声源的角度
              app.BaseLabel.Text =[angel1,' ',angel2];
             polarplot(app.Pax,angle_p,real(10*log(Y')));
             rlim = ([0 5]);
             app.Pax.ThetaZeroLocation = 'top';
             app.Pax.ThetaLim = [-90,90];
             
           end

            % Plot the polar function
%             updateplot(app);
        end

        % Callback function
        function ComponentValueChanged(app, event)
            % Plot the polar function
          
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            value = app.STOPButton.Value;
            quit;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Polar Plot';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'r = ( 1 + sin(a0) )';
            app.ExponentLabel = uilabel(app.UIFigure);
            app.ExponentLabel.Text = '';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bonus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end